classdef MFAgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MFAUIFigure              matlab.ui.Figure
        LOGDropDownLabel         matlab.ui.control.Label
        LOGDropDown              matlab.ui.control.DropDown
        PlotNewButton            matlab.ui.control.Button
        PlotCurrentButton        matlab.ui.control.Button
        GROUPListBoxLabel        matlab.ui.control.Label
        GROUPListBox             matlab.ui.control.ListBox
        SIGNALListBoxLabel       matlab.ui.control.Label
        SIGNALListBox            matlab.ui.control.ListBox
        ZeroShiftTimeCheckBox    matlab.ui.control.CheckBox
        SweepRangeDropDownLabel  matlab.ui.control.Label
        SweepRangeDropDown       matlab.ui.control.DropDown
        UpdateDatabaseButton     matlab.ui.control.Button
        HoldPlotCheckBox         matlab.ui.control.CheckBox
        ZeroMeanCheckBox         matlab.ui.control.CheckBox
        PlotFunctionButton       matlab.ui.control.Button
        PlotFcnList              matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        mfa MFlightAnalysis; % database
        activeLogIdx = 1;
        activeSweep;
        sweepTimes;
    end
    
    methods (Access = private)
        
        function updateMFAData(app)
            % log drop down
            app.updateLogList
            app.updateGroupList
            app.updateSignalList
            app.updateSweepTimes
            % update plot list
            mc = metaclass(app.mfa);
            mlist = arrayfun(@(x) x.Name, mc.MethodList, 'UniformOutput', false);
            mlist(~contains(mlist,'plot_')) = [];
            mlist = cellfun(@(x) strrep(x,'plot_',''), mlist, 'UniformOutput', false);
            app.PlotFcnList.Items = mlist;
        end
        function updateLogList(app)
            logNum = app.mfa.loadedLogs;
            logStr = arrayfun(@(x) num2str(x), logNum,'UniformOutput', false);
            % for each log, get the active axis
            for j=1:numel(app.mfa.logs)
                try
                    tmp = app.mfa.getSignal(j, 'SWP1', 'sax');
                    switch mean(tmp.sax)
                        case 1
                            str = 'ROLL';
                        case 2
                            str = 'PITCH';
                        case 3
                            str = 'YAW';
                        case 4
                            str = 'COL';
                        otherwise
                            str = '';
                    end
                    logStr{j} = sprintf('%s, %s',logStr{j}, str);
                end
            end
            app.LOGDropDown.Items = logStr;
        end
        function mode = getActiveMode(app, time)
            msig = app.mfa.getSignal(app.activeLogIdx, 'MODE', 'Mode');
            mval = msig.Mode(max(find(msig.time < time)));
            switch mval
                case 0
                    mode = 'stab';
                case 1
                    mode = 'acro';
                case 16
                    mode = 'phld';
                otherwise
                    mode = '';
            end
        end
        function updateSweepTimes(app)
            try
                [~, app.sweepTimes] = app.mfa.getSweepIndices('logIndex', app.activeLogIdx);
                swps = arrayfun(@(x) sprintf('%d',x), 1:size(app.sweepTimes, 1), 'UniformOutput', false);
                sflg = app.mfa.getSignal(app.activeLogIdx, 'SWP1', 'sflg');
                for j=1:numel(swps)
                    idx = find(sflg.time >= app.sweepTimes(j,1), 1);
                    tmpTime = sflg.time(idx:end);
                    time = min(tmpTime( find(sflg.sflg(idx:end) == 1) ) );
                    mstr = app.getActiveMode(time);
                    swps{j} = sprintf('%s, %s', swps{j}, mstr);
                end
                opt = horzcat({'-'},swps);
            catch
                opt = {'-'};
            end
            app.SweepRangeDropDown.Items = opt;
        end
        function updateGroupList(app)
            grps = sort(app.mfa.logs{app.activeLogIdx}.log.msgsContained);
            % filter out empty groups
            validGroups = cellfun(@(x) ~isempty(app.mfa.logs{app.activeLogIdx}.log.(x).TimeS), grps);
            app.GROUPListBox.Items = grps(validGroups);
        end
        function updateLogIdx(app)
            app.activeLogIdx = app.mfa.getLogIdx(app.LOGDropDown.Value);
        end
        function updateSignalList(app)
            excludeList = {'LineNo', 'TimeS', 'DatenumUTC', 'TimeUS'};    
            group   = app.GROUPListBox.Value;
            grp     = app.mfa.logs{app.activeLogIdx}.log.(group);
            fn      = fieldnames(grp);
            fn(cellfun(@(x) any(strcmp(x, excludeList)), fn)) = [];
            keep    = false(size(fn));
            tLength = length(grp.TimeS);
            for j=1:numel(fn)
                if isnumeric(grp.(fn{j})) && length(grp.(fn{j})) == tLength
                    keep(j) = true;
                end
            end
            app.SIGNALListBox.Items = sort(fn(keep));
        end
        function plotSignal(app, group, signal)
            sr = app.activeSweep;
            tmp = app.mfa.getSignal( app.activeLogIdx, group, signal);
            if ~strcmp(sr, '-')
               srn = str2double(sr);
               iStart = find(tmp.time <= app.sweepTimes(srn, 1), 1, 'last');
               iStop  = find(tmp.time >= app.sweepTimes(srn, 2), 1, 'first');
               tmp.time = tmp.time(iStart:iStop);
               tmp.(signal) = tmp.(signal)(iStart:iStop);
            end
            if (app.ZeroShiftTimeCheckBox.Value)
                tmp.time = tmp.time - tmp.time(1);
            end
            if (app.ZeroMeanCheckBox.Value)
                tmp.(signal) = tmp.(signal) - mean(tmp.(signal));
            end
            plot(tmp.time, tmp.(signal))
            ylabel(tmp.unit)
            xlabel('Time (s)')
            title(signal)
            grid on
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UpdateDatabaseButton
        function UpdateDatabaseButtonPushed(app, event)
            baseVars = evalin('base','whos');
            baseMFA  = baseVars(arrayfun(@(x) strcmp(x.class, 'MFlightAnalysis'), baseVars));
            if isempty(baseMFA)
                error('no base vars of correct type')
            elseif numel(baseMFA) > 1
                error('more than one instance')
            end
            app.mfa = evalin('base',baseMFA.name);
            app.updateMFAData
        end

        % Value changed function: GROUPListBox
        function GROUPListBoxValueChanged(app, event)
            app.updateSignalList
        end

        % Value changed function: LOGDropDown
        function LOGDropDownValueChanged(app, event)
            value = app.LOGDropDown.Value;
            tmp = regexp(value, ',','split');
            app.activeLogIdx = app.mfa.getLogIdx(str2double(tmp{1}));
            app.updateSweepTimes
            app.updateGroupList
            app.updateSignalList
           
        end

        % Button pushed function: PlotNewButton
        function PlotNewButtonPushed(app, event)
            figure
            app.plotSignal(app.GROUPListBox.Value, app.SIGNALListBox.Value)
            if app.HoldPlotCheckBox.Value
                hold on
            end
        end

        % Button pushed function: PlotCurrentButton
        function PlotCurrentButtonPushed(app, event)
            if app.HoldPlotCheckBox.Value
                hold(gca,'on');
            else
                hold(gca,'off');
            end
            
            app.plotSignal(app.GROUPListBox.Value, app.SIGNALListBox.Value)
        end

        % Value changed function: SweepRangeDropDown
        function SweepRangeDropDownValueChanged(app, event)
            value = app.SweepRangeDropDown.Value;
            tmp = regexp(value, ',', 'split');
            app.activeSweep = tmp{1};
        end

        % Button pushed function: PlotFunctionButton
        function PlotFunctionButtonPushed(app, event)
            if ~strcmp(app.activeSweep,'-')
                app.mfa.plot(app.PlotFcnList.Value, 'logNum', app.mfa.logs{app.activeLogIdx}.num,...
                                                    'swpEvent', str2double(app.activeSweep) )
            else
                app.mfa.plot(app.PlotFcnList.Value, 'logNum', app.mfa.logs{app.activeLogIdx}.num)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MFAUIFigure = uifigure('Visible', 'off');
            app.MFAUIFigure.Name = 'MFA';
            app.LOGDropDownLabel = uilabel(app.MFAUIFigure);
            app.LOGDropDownLabel.Text = 'LOG';
            app.LOGDropDown = uidropdown(app.MFAUIFigure);
            app.LOGDropDown.ValueChangedFcn = createCallbackFcn(app, @LOGDropDownValueChanged, true);
            app.PlotNewButton = uibutton(app.MFAUIFigure, 'push');
            app.PlotNewButton.ButtonPushedFcn = createCallbackFcn(app, @PlotNewButtonPushed, true);
            app.PlotNewButton.Text = 'Plot New';
            app.PlotCurrentButton = uibutton(app.MFAUIFigure, 'push');
            app.PlotCurrentButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCurrentButtonPushed, true);
            app.PlotCurrentButton.Text = 'Plot Current';
            app.GROUPListBoxLabel = uilabel(app.MFAUIFigure);
            app.GROUPListBoxLabel.Text = 'GROUP';
            app.GROUPListBox = uilistbox(app.MFAUIFigure);
            app.GROUPListBox.ValueChangedFcn = createCallbackFcn(app, @GROUPListBoxValueChanged, true);
            app.SIGNALListBoxLabel = uilabel(app.MFAUIFigure);
            app.SIGNALListBoxLabel.Text = 'SIGNAL';
            app.SIGNALListBox = uilistbox(app.MFAUIFigure);
            app.ZeroShiftTimeCheckBox = uicheckbox(app.MFAUIFigure);
            app.ZeroShiftTimeCheckBox.Text = 'Zero Shift Time';
            app.ZeroShiftTimeCheckBox.Value = true;
            app.SweepRangeDropDownLabel = uilabel(app.MFAUIFigure);
            app.SweepRangeDropDownLabel.Text = 'Sweep Range';
            app.SweepRangeDropDown = uidropdown(app.MFAUIFigure);
            app.SweepRangeDropDown.ValueChangedFcn = createCallbackFcn(app, @SweepRangeDropDownValueChanged, true);
            app.UpdateDatabaseButton = uibutton(app.MFAUIFigure, 'push');
            app.UpdateDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDatabaseButtonPushed, true);
            app.UpdateDatabaseButton.Text = 'Update Database';
            app.HoldPlotCheckBox = uicheckbox(app.MFAUIFigure);
            app.HoldPlotCheckBox.Text = 'Hold Plot';
            app.ZeroMeanCheckBox = uicheckbox(app.MFAUIFigure);
            app.ZeroMeanCheckBox.Text = 'ZeroMean';
            app.PlotFcnList = uidropdown(app.MFAUIFigure);
            app.PlotFunctionButton = uibutton(app.MFAUIFigure, 'push');
            app.PlotFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotFunctionButtonPushed, true);
            app.PlotFunctionButton.Text = 'Plot Function';
            % Show the figure after all components are created
            app.MFAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MFAgui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MFAUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MFAUIFigure)
        end
    end
end